classdef MaREA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MaREAUIFigure                   matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        DatasetTab                      matlab.ui.container.Tab
        importDataset                   matlab.ui.control.Button
        AdddatasetButton                matlab.ui.control.Button
        DatasetLabel                    matlab.ui.control.Label
        GeneIDscolDropDownLabel         matlab.ui.control.Label
        GeneIDscolDropDown              matlab.ui.control.DropDown
        FirstpatientcolDropDownLabel    matlab.ui.control.Label
        FirstpatientcolDropDown         matlab.ui.control.DropDown
        GeneIdentifierListBoxLabel      matlab.ui.control.Label
        GeneIdentifierListBox           matlab.ui.control.ListBox
        DatasetsTextAreaLabel           matlab.ui.control.Label
        DatasetsTextArea                matlab.ui.control.TextArea
        DatasetName                     matlab.ui.control.Label
        MetabolicModelTab               matlab.ui.container.Tab
        MetabolicmodelListBoxLabel      matlab.ui.control.Label
        MetabolicmodelListBox           matlab.ui.control.ListBox
        MetabolicmodelLabel             matlab.ui.control.Label
        ModelTextAreaLabel              matlab.ui.control.Label
        ModelTextArea                   matlab.ui.control.TextArea
        SelectModelButton               matlab.ui.control.Button
        ModelGeneIdentifierLabel        matlab.ui.control.Label
        ModelGeneIdentifierListBox      matlab.ui.control.ListBox
        PreparedataTab                  matlab.ui.container.Tab
        DatasetListBoxLabel             matlab.ui.control.Label
        DatasetListBox                  matlab.ui.control.ListBox
        ComputeRASButton                matlab.ui.control.Button
        ResultTextAreaLabel             matlab.ui.control.Label
        ResultTextArea                  matlab.ui.control.TextArea
        AdvancedOptionsSwitchLabel      matlab.ui.control.Label
        AdvancedOptionsSwitch           matlab.ui.control.Switch
        ResolveAandnanrulesSwitchLabel  matlab.ui.control.Label
        ResolveAandnanrulesSwitch       matlab.ui.control.Switch
        ConvertlogscaledtodecimalvaluesSwitchLabel  matlab.ui.control.Label
        ConvertlogscaledtodecimalvaluesSwitch  matlab.ui.control.Switch
        Threshold0transcriptSpinnerLabel  matlab.ui.control.Label
        Threshold0transcriptSpinner     matlab.ui.control.Spinner
        ParallelemodeSwitchLabel        matlab.ui.control.Label
        ParallelemodeSwitch             matlab.ui.control.Switch
        AnalysisTab                     matlab.ui.container.Tab
        DatasetswithRASListBoxLabel     matlab.ui.control.Label
        DatasetswithRASListBox          matlab.ui.control.ListBox
        TabGroup2                       matlab.ui.container.TabGroup
        ComparedatasetTab               matlab.ui.container.Tab
        ComparedatasetButton            matlab.ui.control.Button
        ModeDropDownLabel               matlab.ui.control.Label
        ModeDropDown                    matlab.ui.control.DropDown
        HypotesistestDropDownLabel      matlab.ui.control.Label
        HypotesistestDropDown           matlab.ui.control.DropDown
        FoldChangeDropDownLabel         matlab.ui.control.Label
        FoldChangeDropDown              matlab.ui.control.DropDown
        ColormapButton                  matlab.ui.control.Button
        ClusteringTab                   matlab.ui.container.Tab
        ExportDataButton                matlab.ui.control.Button
    end

    
    properties (Access = public)
        TRdataset % Description
        datasetFileName
        colsName
        colFirstPatient
        metModel
        RAS
        datasetsStr
        HMRcoreFLAG
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.GeneIDscolDropDown.Visible = 'off';
            app.GeneIDscolDropDownLabel.Visible = 'off';
            app.GeneIdentifierListBox.Visible = 'off';
            app.GeneIdentifierListBoxLabel.Visible = 'off';
            app.FirstpatientcolDropDown.Visible = 'off';
            app.GeneIdentifierListBox.Visible = 'off';
            app.AdddatasetButton.Visible = 'off';
            app.FirstpatientcolDropDownLabel.Visible = 'off';
            app.GeneIdentifierListBoxLabel.Visible = 'off';
            app.DatasetsTextArea.Visible = 'off';
            app.DatasetsTextAreaLabel.Visible = 'off';
            app.DatasetName.Visible = 'off';
            app.DatasetsTextArea.Value = {''};
            app.ModelTextArea.Visible = 'off';
            app.ModelTextAreaLabel.Visible = 'off';
            app.ResolveAandnanrulesSwitch.Visible = 'off';
            app.ConvertlogscaledtodecimalvaluesSwitch.Visible = 'off';
            app.ParallelemodeSwitch.Visible = 'off';
            app.ParallelemodeSwitchLabel.Visible = 'off';
            app.Threshold0transcriptSpinner.Visible = 'off';
            app.ResolveAandnanrulesSwitchLabel.Visible = 'off';
            app.ConvertlogscaledtodecimalvaluesSwitchLabel.Visible = 'off';
            app.Threshold0transcriptSpinnerLabel.Visible = 'off';
            app.ComparedatasetButton.Enable = 'off';
            app.ExportDataButton.Enable = 'off';
            app.HMRcoreFLAG = false;
            app.ColormapButton.Visible = 'off';
        end

        % Button pushed function: importDataset
        function importDatasetButtonPushed(app, event)
            app.importDataset.Enable = 'off';
            [app.datasetFileName, datasetPath] = uigetfile({'*.txt';'*.csv';'*.*'}, 'Select dataset');
            
            if ~isequal(app.datasetFileName,0)
                
                fid = fopen([datasetPath, app.datasetFileName]);
                n = fgetl(fid);
                n(regexp(n,'[.,-]'))='_';
                
                s = strsplit(n, '\t');
                if length(s)==1
                    s = strsplit(n, ',');
                    if length(s)==1
                        s = strsplit(n, ';');
                        if length(s)==1
                            app.importDataset.Enable = 'on';
                            error('Only tab-separated-values or command-separated-value file allowed.')
                        end
                    end
                end
                if strcmp(s(end),'')
                    s(end) = [];
                end
                fclose(fid);
                
                app.TRdataset = readtable([datasetPath, app.datasetFileName], 'ReadVariableNames',false, 'HeaderLines', 1);
                app.TRdataset = app.TRdataset(:,1:length(s));
                app.TRdataset.Properties.VariableNames = s;
                app.colsName = app.TRdataset.Properties.VariableNames;
                app.GeneIDscolDropDown.Items = app.colsName;
                app.FirstpatientcolDropDown.Items = app.colsName;
                app.DatasetName.Text = app.datasetFileName;
                msgbox('Dataset Loaded');
                app.importDataset.Enable = 'on';
                
                app.GeneIDscolDropDown.Visible = 'on';
                app.GeneIDscolDropDownLabel.Visible = 'on';
                app.GeneIdentifierListBox.Visible = 'on';
                app.GeneIdentifierListBoxLabel.Visible = 'on';
                app.FirstpatientcolDropDown.Visible = 'on';
                app.GeneIdentifierListBox.Visible = 'on';
                app.MetabolicmodelListBox.Visible = 'on';
                app.AdddatasetButton.Visible = 'on';
                app.FirstpatientcolDropDownLabel.Visible = 'on';
                app.GeneIdentifierListBoxLabel.Visible = 'on';
                app.MetabolicmodelListBoxLabel.Visible = 'on';
                app.DatasetName.Visible = 'on';
            end
        end

        % Button pushed function: AdddatasetButton
        function AdddatasetButtonPushed(app, event)
            app.DatasetsTextArea.Visible = 'on';
            app.DatasetsTextAreaLabel.Visible = 'on';
            
            geneList = app.TRdataset.(app.GeneIDscolDropDown.Value);
            
            p = find(strcmp(app.FirstpatientcolDropDown.Value, app.colsName));
            try
                transcript = app.TRdataset{:,p:end};
            catch
                error('Can not concatenate patient columns. Please check.');
            end
            
            Text = [{app.datasetFileName};{['    Genes: ' num2str(length(geneList))]};{['    Patients: ' num2str(size(transcript,2))]}];
            %             if strcmp(app.DatasetsTextArea.Value, '')
            %                 app.DatasetsTextArea.Value = Text;
            %             else
            %                 app.DatasetsTextArea.Value = [app.DatasetsTextArea.Value; Text];
            %             end
            
            p_pos = strfind(app.datasetFileName, '.');
            %app.datasetsStr.(app.datasetFileName(1:p_pos-1)).transcript = transcript;
            %app.datasetsStr.(app.datasetFileName(1:p_pos-1)).geneList = geneList;
            
            if ~isfield(app.datasetsStr, app.datasetFileName(1:p_pos-1))
                app.datasetsStr.(app.datasetFileName(1:p_pos-1)).dataset = app.TRdataset;
                app.datasetsStr.(app.datasetFileName(1:p_pos-1)).colFirstPatient = p;
                app.datasetsStr.(app.datasetFileName(1:p_pos-1)).colGeneID = find(strcmp(app.TRdataset.Properties.VariableNames, app.GeneIDscolDropDown.Value));
                app.datasetsStr.(app.datasetFileName(1:p_pos-1)).patientIDs = app.colsName(p:end);
                if strcmp(app.DatasetsTextArea.Value, '')
                    app.DatasetsTextArea.Value = Text;
                else
                    app.DatasetsTextArea.Value = [app.DatasetsTextArea.Value; Text];
                end
                app.DatasetListBox.Items = [app.DatasetListBox.Items, app.datasetFileName(1:p_pos-1)];
            else
                answer = questdlg(['Dataset ', app.datasetFileName(1:p_pos-1), ', already added. Replace it?'], 'Adding dataset', 'Yes','No','No');
                if strcmp(answer, 'Yes')
                    app.datasetsStr.(app.datasetFileName(1:p_pos-1)).dataset = app.TRdataset;
                    app.datasetsStr.(app.datasetFileName(1:p_pos-1)).colFirstPatient = p;
                    app.datasetsStr.(app.datasetFileName(1:p_pos-1)).colGeneID = find(strcmp(app.TRdataset.Properties.VariableNames, app.GeneIDscolDropDown.Value));
                    app.datasetsStr.(app.datasetFileName(1:p_pos-1)).patientIDs = app.colsName(p:end);
                    pos_rm = find(strcmp(app.DatasetsTextArea.Value, app.datasetFileName));
                    app.DatasetsTextArea.Value(pos_rm:pos_rm+2)= Text;
                else
                    msgbox('Dataset not loaded.')
                end
            end
            switch app.GeneIdentifierListBox.Value
                case {'Hugo Symbol'}
                    app.datasetsStr.(app.datasetFileName(1:p_pos-1)).geneIdentifier = 'symbol';
                case {'Hugo ID'}
                    app.datasetsStr.(app.datasetFileName(1:p_pos-1)).geneIdentifier = 'hgnc_id';
                case {'Ensemble ID'}
                    app.datasetsStr.(app.datasetFileName(1:p_pos-1)).geneIdentifier = 'ensembl_gene_id';
                case {'Entrez ID'}
                    app.datasetsStr.(app.datasetFileName(1:p_pos-1)).geneIdentifier = 'entrez_id';
            end


        end

        % Button pushed function: SelectModelButton
        function SelectModelButtonPushed(app, event)
            app.SelectModelButton.Enable = 'off';
            if strcmp(app.MetabolicmodelListBox.Value, 'Recon2.2')
                app.metModel = load('Recon22.mat');
                f = fieldnames(app.metModel);
                app.metModel = app.metModel.(f{1});
                app.metModel.description = 'Recon2.2';
                app.metModel.genIdentifier = 'hgnc_id';
                app.HMRcoreFLAG = false;
            elseif strcmp(app.MetabolicmodelListBox.Value, 'HMRcore')
                app.metModel = load('HMRcore.mat');
                f = fieldnames(app.metModel);
                app.metModel = app.metModel.(f{1});
                app.metModel.description = 'HMRcore';
                app.metModel.genIdentifier = 'hgnc_id';
                app.HMRcoreFLAG = true;
            else
                app.HMRcoreFLAG = false;
                [modelFile, modelPath] = uigetfile({'*.mat';'*.xlm';'*.xls';'*.*'}, 'Select metabolic model');
                if modelFile == 0
                    app.SelectModelButton.Enable = 'on';
                    return
                end
                switch modelFile(end-2:end)
                    case 'mat'
                        app.metModel = load([modelPath, modelFile]);
                        f = fieldnames(app.metModel);
                        app.metModel = app.metModel.(f{1});
                    case 'xlm'
                        app.metModel =  readCbModel([modelPath, modelFile]);
                    case ('xls' | 'lsx')
                        app.metModel =  xls2model([modelPath, modelFile]);
                    otherwise
                        error('Model not supported')
                end
                p_pos = strfind(modelFile, '.');
                app.metModel.description = modelFile(1:p_pos-1);
                switch app.ModelGeneIdentifierListBox.Value
                    case 'Hugo Symbol'
                        app.metModel.genIdentifier = 'symbol';
                    case 'Hugo ID'
                        app.metModel.genIdentifier = 'hgnc_id';
                    case 'Ensemble ID'
                        app.metModel.genIdentifier = 'ensembl_gene_id';
                    case 'Entrez ID'
                        app.metModel.genIdentifier = 'entrez_id';
                end
            end
            msgbox('Metabolic model loaded');
            Text = [{app.metModel.description};{['    Genes: ', num2str(length(app.metModel.genes))]};{['    Reaction: ', num2str(length(app.metModel.rxns))]}; {['        with GPR: ', num2str(length(find(~strcmp(app.metModel.rules, ''))))]}];
            app.ModelTextArea.Value =  Text;
            app.ModelTextArea.Visible = 'on';
            app.ModelTextAreaLabel.Visible = 'on';
            app.SelectModelButton.Enable = 'on';
        end

        % Button pushed function: ComputeRASButton
        function ComputeRASButtonPushed(app, event)
            app.ComputeRASButton.Enable = 'off';
            if isempty(app.DatasetListBox.Value)
                msgbox('Please add or select at least one dataset');
            elseif ~isstruct(app.metModel)
                msgbox('Please select a metabolic model');
            else
                for i=1:length(app.DatasetListBox.Value)
                    if ~isfield(app.datasetsStr.(app.DatasetListBox.Value{i}), 'datasetFilt')
                        dataset_i = app.datasetsStr.(app.DatasetListBox.Value{i});
                        if ~strcmp(dataset_i.geneIdentifier, app.metModel.genIdentifier) && ~isfield(app.metModel, [app.metModel.genIdentifier, '2', dataset_i.geneIdentifier])
                            dict_i = getGeneIDs(app.metModel.genes, {app.metModel.genIdentifier}, {dataset_i.geneIdentifier}, [app.metModel.genIdentifier, '2', dataset_i.geneIdentifier, '_log.txt']);
                            app.metModel.([app.metModel.genIdentifier, '2', dataset_i.geneIdentifier]) = dict_i(:,[3 2]);
                            amb = find(strcmp(app.metModel.([app.metModel.genIdentifier, '2', dataset_i.geneIdentifier]).(1), 'ambiguous'), 1);
                            if ~isempty(amb)
                                msgbox(['Some genes are ambiguos, please check file', app.metModel.genIdentifier, '2', dataset_i.geneIdentifier, '_log.txt',' for details'])
                            end
                            % filtro dataset
                            [app.datasetsStr.(app.DatasetListBox.Value{i}).datasetFilt, ~, genNotInModel, genDuplicated] = extractGeneRow(app.metModel, dataset_i.dataset(:,[dataset_i.colGeneID, dataset_i.colFirstPatient:end]), 1, dict_i(:,[3 2]));
                            colS = 3;
                        elseif isfield(app.metModel, [app.metModel.genIdentifier, '2', dataset_i.geneIdentifier])
                            [app.datasetsStr.(app.DatasetListBox.Value{i}).datasetFilt, ~, genNotInModel, genDuplicated] = extractGeneRow(app.metModel, dataset_i.dataset(:,[dataset_i.colGeneID, dataset_i.colFirstPatient:end]), 1, app.metModel.([app.metModel.genIdentifier, '2', dataset_i.geneIdentifier]));
                            colS = 3;
                        else
                            [app.datasetsStr.(app.DatasetListBox.Value{i}).datasetFilt, ~, genNotInModel, genDuplicated] = extractGeneRow(app.metModel, dataset_i.dataset(:,[dataset_i.colGeneID, dataset_i.colFirstPatient:end]), 1);
                            colS = 2;
                        end
                        %BRCAscore_abs_CORE = getDataScored(HMRcore, BRCAfilt_CORE.Input, BRCAfilt_CORE{:,3:end}, BRCAfilt_CORE.Properties.VariableNames(3:end), true);
                        disp('Dataset(s) filtered');
                    end
                    if ~isfield(app.datasetsStr.(app.DatasetListBox.Value{i}), 'datasetSCORE')
                        if strcmp(app.ParallelemodeSwitch.Value, 'No')
                            app.datasetsStr.(app.DatasetListBox.Value{i}).datasetSCORE = getDataScored(app.metModel, app.datasetsStr.(app.DatasetListBox.Value{i}).datasetFilt.Input, app.datasetsStr.(app.DatasetListBox.Value{i}).datasetFilt{:, colS:end}, app.datasetsStr.(app.DatasetListBox.Value{i}).datasetFilt.Properties.VariableNames(colS:end), false);
                        else
                            app.datasetsStr.(app.DatasetListBox.Value{i}).datasetSCORE = getDataScored(app.metModel, app.datasetsStr.(app.DatasetListBox.Value{i}).datasetFilt.Input, app.datasetsStr.(app.DatasetListBox.Value{i}).datasetFilt{:, colS:end}, app.datasetsStr.(app.DatasetListBox.Value{i}).datasetFilt.Properties.VariableNames(colS:end), true);
                            
                        end
                        app.DatasetswithRASListBox.Items =  [app.DatasetswithRASListBox.Items, app.DatasetListBox.Value{i}];
                    else
                        disp(['RAS for dataset ', app.DatasetListBox.Value{i}, ' already computed.']);
                    end
                end
                %                     if ~strcmp(dataset_i.geneIdentifier, 'Hugo ID') %crea dizionario traducendo geni del modello
                %                         switch dataset_i.geneIdentifier
                %                             case 'Hugo Symbol'
                %                                 if ~isfield(app.metModel, 'HGNCid2Symb')
                %                                     dict_i = getGeneIDs(app.metModel.genes, {app.metModel.genIdentifier}, {'symbol'}, 'hgncid2symbol_log.txt');
                %                                     app.metModel.HGNCid2Symb = dict_i(:,[3 2]);
                %                                     amb = find(strcmp(HMRcore.HGNCid2Symb.(1), 'ambiguous'), 1);
                %                                     if ~isempty(amb)
                %                                         msgbox('Some genes are ambiguos, please check file hgncid2symbol_log.txt for details')
                %                                     end
                %                                 end
                %                             case 'Ensemble ID'
                %                                 if ~isfield(app.metModel, 'HGNCid2ENS')
                %                                     dict_i = getGeneIDs(app.metModel.genes, {app.metModel.genIdentifier}, {'ensembl_gene_id'}, 'hgncid2ensemble_log.txt');
                %                                     app.metModel.HGNCid2ENS = dict_i(:,[3 2]);
                %                                     amb = find(strcmp(HMRcore.HGNCid2ENS.(1), 'ambiguous'), 1);
                %                                     if ~isempty(amb)
                %                                         msgbox('Some genes are ambiguos, please check file hgncid2ensemble_log.txt for details')
                %                                     end
                %                                 end
                %                             case 'Entrez ID'
                %                                 if ~isfield(app.metModel, 'HGNCid2Etrz')
                %                                     dict_i = getGeneIDs(app.metModel.genes, {app.metModel.genIdentifier}, {'entrez_id'}, 'hgncid2entrez_log.txt');
                %                                     app.metModel.HGNCid2Etrz = dict_i(:,[3 2]);
                %                                     amb = find(strcmp(HMRcore.HGNCid2Etrz.(1), 'ambiguous'), 1);
                %                                     if ~isempty(amb)
                %                                         msgbox('Some genes are ambiguos, please check file hgncid2entrez_log.txt for details')
                %                                     end
                %                                 end
                
                
                %Text = [{app.metModel.description}; {['    Genes: ', num2str(length(app.metModel.genes))]};{['    Reaction: ', num2str(length(app.metModel.rxns))]}; {['        with rules: ', num2str(length(find(~strcmp(app.metModel.rules, ''))))]}];
                msgbox('dataset(s) ready for analysis');
                app.ComparedatasetButton.Enable = 'on';
                app.ExportDataButton.Enable = 'on';
                
            end
            app.ComputeRASButton.Enable = 'on';
        end

        % Value changed function: MetabolicmodelListBox
        function MetabolicmodelListBoxValueChanged(app, event)
            value = app.MetabolicmodelListBox.Value;
            if (strcmp(value, 'HMRcore') || strcmp(value, 'Recon2.2'))
                app.ModelGeneIdentifierListBox.Value = 'Hugo ID';
                app.ModelGeneIdentifierLabel.Enable = 'off';
                app.ModelGeneIdentifierListBox.Enable = 'off';
            else
                app.ModelGeneIdentifierListBox.Enable = 'on';
                app.ModelGeneIdentifierLabel.Enable = 'on';
            end
        end

        % Value changed function: AdvancedOptionsSwitch
        function AdvancedOptionsSwitchValueChanged(app, event)
            value = app.AdvancedOptionsSwitch.Value;
            if strcmp(value, 'Default')
                app.ResolveAandnanrulesSwitch.Visible = 'off';
                app.ResolveAandnanrulesSwitch.Value = 'A';
                app.ConvertlogscaledtodecimalvaluesSwitch.Visible = 'off';
                app.ConvertlogscaledtodecimalvaluesSwitch.Value = 'No';
                app.Threshold0transcriptSpinner.Visible = 'off';
                app.Threshold0transcriptSpinner.Value = 0.0;
                app.ResolveAandnanrulesSwitchLabel.Visible = 'off';
                app.ConvertlogscaledtodecimalvaluesSwitchLabel.Visible = 'off';
                app.Threshold0transcriptSpinnerLabel.Visible = 'off';
                app.ParallelemodeSwitch.Visible = 'off';
                app.ParallelemodeSwitch.Value = 'No';
                app.ParallelemodeSwitchLabel.Visible = 'off';
            else
                app.ResolveAandnanrulesSwitch.Visible = 'on';
                app.ConvertlogscaledtodecimalvaluesSwitch.Visible = 'on';
                app.Threshold0transcriptSpinner.Visible = 'on';
                app.ResolveAandnanrulesSwitchLabel.Visible = 'on';
                app.ConvertlogscaledtodecimalvaluesSwitchLabel.Visible = 'on';
                app.Threshold0transcriptSpinnerLabel.Visible = 'on';
                app.ParallelemodeSwitch.Visible = 'on';
                app.ParallelemodeSwitchLabel.Visible = 'on';
            end
        end

        % Button pushed function: ComparedatasetButton
        function ComparedatasetButtonPushed(app, event)
            % creare strutture con i database con score.
            % calcFCpVonCluster(clstCOREk3_1vs3, 'method', 'combinatorial', 'test', 'kstest2', 'normalization', 'none', 'log2fc', true, 'absFC', true, 'header', true);
            if length(app.DatasetswithRASListBox.Value) < 2
                msgbox('Select at least two datasets');
                return
            end
            app.ComparedatasetButton.Enable = 'on';
            for i=1:length(app.DatasetswithRASListBox.Value)
                StrAnalysis.(app.DatasetswithRASListBox.Value{i}) = convertTable_Cell(app.datasetsStr.(app.DatasetswithRASListBox.Value{i}).datasetSCORE);
            end
            
            if strcmp(app.ModeDropDown.Value, 'Pairwise')
                method = 'combinatorial';
            else
                method = 'single';
            end
            
            switch app.HypotesistestDropDown.Value
                case 'Kolmogorov - Smirnov'
                    test = 'kstest2';
                case 'Kruskal - Wallis'
                    test = 'kruskalwallis';
                case 't student'
                    test = 'ttest2';
                case 'One way anova'
                    test = 'anova1';
                case 'Do not compute'
                    test = 'none';
            end
            
            switch app.FoldChangeDropDown.Value
                case 'Compute and log2 scale'
                    fc = true;
                    log2fc = true;
                    absFC = true;
                case 'Compute'
                    fc = true;
                    log2fc = false;
                    absFC = false;
                case 'Do not compute'
                    fc = false;
                    log2fc = false;
                    absFC = false;
            end
            
            [fileName,filePath] = uiputfile('*.txt', 'Save statistical analysis');
            
            tableStat = calcFCpVonCluster(StrAnalysis, 'method', method , 'test', test , 'normalization', 'none', 'fc', fc, 'log2fc', log2fc, 'absFC', absFC, 'header', true);
            for i=1:size(tableStat, 1)
                idx = findIdString_v2(tableStat.(1)(i), app.metModel.rxns);
                tableStat.Pathway(i) = app.metModel.subSystems(idx);
                tableStat.Description(i) = app.metModel.rxnNames(idx);
                tableStat.GeneRule(i) = app.metModel.grRules(idx);
                tableStat.Reaction(i) = printRxnFormula(app.metModel, tableStat.(1)(i));
            end
            writetable(tableStat, [fileName,filePath], 'Delimiter', '\t');
            
            msgbox('File saved');
            app.ComparedatasetButton.Enable = 'on';
        end

        % Button pushed function: ExportDataButton
        function ExportDataButtonPushed(app, event)
            if ~isempty(app.DatasetswithRASListBox.Value)
                for i=1:length(app.DatasetswithRASListBox.Value)
                    fileName = 0;
                    [fileName,filePath] = uiputfile('*.mat', ['Save ', app.DatasetswithRASListBox.Value{i}]);
                    if fileName == 0
                        return
                    end
                    %saveVar = app.datasetsStr.(app.DatasetswithRASListBox.Value{i});
                    n = strsplit(fileName, '.');
                    try
                        S.(n{1}) = app.datasetsStr.(app.DatasetswithRASListBox.Value{i});
                        save([filePath,fileName], '-struct', 'S');
                    catch
                        msgbox('Failed, invalid file name');
                    end
                    msgbox('Dataset saved.');
                end
            else
                msgbox('Select at least one dataset');
            end
        end

        % Button pushed function: ColormapButton
        function ColormapButtonPushed(app, event)
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MaREAUIFigure = uifigure;
            app.MaREAUIFigure.Name = 'MaREA';
            setAutoResize(app, app.MaREAUIFigure, true)
            app.TabGroup = uitabgroup(app.MaREAUIFigure);
            app.DatasetTab = uitab(app.TabGroup);
            app.DatasetTab.Title = 'Dataset';
            app.DatasetTab.Units = 'pixels';
            app.importDataset = uibutton(app.DatasetTab, 'push');
            app.importDataset.ButtonPushedFcn = createCallbackFcn(app, @importDatasetButtonPushed, true);
            app.importDataset.Text = 'Select dataset';
            app.AdddatasetButton = uibutton(app.DatasetTab, 'push');
            app.AdddatasetButton.ButtonPushedFcn = createCallbackFcn(app, @AdddatasetButtonPushed, true);
            app.AdddatasetButton.Text = 'Add dataset';
            app.DatasetLabel = uilabel(app.DatasetTab);
            app.DatasetLabel.Text = 'Dataset';
            app.GeneIDscolDropDownLabel = uilabel(app.DatasetTab);
            app.GeneIDscolDropDownLabel.Text = 'Gene IDs col';
            app.GeneIDscolDropDown = uidropdown(app.DatasetTab);
            app.FirstpatientcolDropDownLabel = uilabel(app.DatasetTab);
            app.FirstpatientcolDropDownLabel.Text = 'First patient col';
            app.FirstpatientcolDropDown = uidropdown(app.DatasetTab);
            app.GeneIdentifierListBoxLabel = uilabel(app.DatasetTab);
            app.GeneIdentifierListBoxLabel.Text = 'Gene Identifier';
            app.GeneIdentifierListBox = uilistbox(app.DatasetTab);
            app.GeneIdentifierListBox.Items = {'Hugo Symbol', 'Hugo ID', 'Ensemble ID', 'Entrez ID'};
            app.GeneIdentifierListBox.Value = 'Hugo Symbol';
            app.DatasetsTextAreaLabel = uilabel(app.DatasetTab);
            app.DatasetsTextAreaLabel.Text = 'Datasets';
            app.DatasetsTextArea = uitextarea(app.DatasetTab);
            app.DatasetsTextArea.Editable = 'off';
            app.DatasetsTextArea.Value = {''; ''};
            app.DatasetName = uilabel(app.DatasetTab);
            app.DatasetName.Text = '';
            app.MetabolicModelTab = uitab(app.TabGroup);
            app.MetabolicModelTab.Title = 'Metabolic Model';
            app.MetabolicModelTab.Units = 'pixels';
            app.MetabolicmodelListBoxLabel = uilabel(app.MetabolicModelTab);
            app.MetabolicmodelListBoxLabel.Text = 'Metabolic model';
            app.MetabolicmodelListBox = uilistbox(app.MetabolicModelTab);
            app.MetabolicmodelListBox.Items = {'HMRcore', 'Recon2.2', 'Custom'};
            app.MetabolicmodelListBox.ValueChangedFcn = createCallbackFcn(app, @MetabolicmodelListBoxValueChanged, true);
            app.MetabolicmodelListBox.Value = 'HMRcore';
            app.MetabolicmodelLabel = uilabel(app.MetabolicModelTab);
            app.MetabolicmodelLabel.Text = 'Metabolic model';
            app.ModelTextAreaLabel = uilabel(app.MetabolicModelTab);
            app.ModelTextAreaLabel.Text = 'Model';
            app.ModelTextArea = uitextarea(app.MetabolicModelTab);
            app.ModelTextArea.Editable = 'off';
            app.ModelTextArea.Value = {''; ''};
            app.SelectModelButton = uibutton(app.MetabolicModelTab, 'push');
            app.SelectModelButton.ButtonPushedFcn = createCallbackFcn(app, @SelectModelButtonPushed, true);
            app.SelectModelButton.Text = 'Select Model';
            app.ModelGeneIdentifierLabel = uilabel(app.MetabolicModelTab);
            app.ModelGeneIdentifierLabel.Enable = 'off';
            app.ModelGeneIdentifierLabel.Text = {'Model Gene '; 'Identifier'};
            app.ModelGeneIdentifierListBox = uilistbox(app.MetabolicModelTab);
            app.ModelGeneIdentifierListBox.Items = {'Hugo Symbol', 'Hugo ID', 'Ensemble ID', 'Entrez ID'};
            app.ModelGeneIdentifierListBox.Enable = 'off';
            app.ModelGeneIdentifierListBox.Value = 'Hugo ID';
            app.PreparedataTab = uitab(app.TabGroup);
            app.PreparedataTab.Title = 'Prepare data';
            app.PreparedataTab.Units = 'pixels';
            app.DatasetListBoxLabel = uilabel(app.PreparedataTab);
            app.DatasetListBoxLabel.Text = 'Dataset';
            app.DatasetListBox = uilistbox(app.PreparedataTab);
            app.DatasetListBox.Items = {};
            app.DatasetListBox.Multiselect = 'on';
            app.DatasetListBox.Value = {};
            app.ComputeRASButton = uibutton(app.PreparedataTab, 'push');
            app.ComputeRASButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeRASButtonPushed, true);
            app.ComputeRASButton.Text = 'Compute RAS';
            app.ResultTextAreaLabel = uilabel(app.PreparedataTab);
            app.ResultTextAreaLabel.Text = 'Result';
            app.ResultTextArea = uitextarea(app.PreparedataTab);
            app.AdvancedOptionsSwitchLabel = uilabel(app.PreparedataTab);
            app.AdvancedOptionsSwitchLabel.Text = 'Advanced Options';
            app.AdvancedOptionsSwitch = uiswitch(app.PreparedataTab, 'slider');
            app.AdvancedOptionsSwitch.Items = {'Default', 'Custom'};
            app.AdvancedOptionsSwitch.ValueChangedFcn = createCallbackFcn(app, @AdvancedOptionsSwitchValueChanged, true);
            app.AdvancedOptionsSwitch.Value = 'Default';
            app.ResolveAandnanrulesSwitchLabel = uilabel(app.PreparedataTab);
            app.ResolveAandnanrulesSwitchLabel.Text = 'Resolve (A and nan) rules';
            app.ResolveAandnanrulesSwitch = uiswitch(app.PreparedataTab, 'slider');
            app.ResolveAandnanrulesSwitch.Items = {'A', 'nan'};
            app.ResolveAandnanrulesSwitch.Value = 'A';
            app.ConvertlogscaledtodecimalvaluesSwitchLabel = uilabel(app.PreparedataTab);
            app.ConvertlogscaledtodecimalvaluesSwitchLabel.Text = 'Convert log scaled to decimal values';
            app.ConvertlogscaledtodecimalvaluesSwitch = uiswitch(app.PreparedataTab, 'slider');
            app.ConvertlogscaledtodecimalvaluesSwitch.Items = {'No', 'Yes'};
            app.ConvertlogscaledtodecimalvaluesSwitch.Value = 'No';
            app.Threshold0transcriptSpinnerLabel = uilabel(app.PreparedataTab);
            app.Threshold0transcriptSpinnerLabel.Text = 'Threshold 0 transcript';
            app.Threshold0transcriptSpinner = uispinner(app.PreparedataTab);
            app.Threshold0transcriptSpinner.ValueDisplayFormat = '%.1f';
            app.ParallelemodeSwitchLabel = uilabel(app.PreparedataTab);
            app.ParallelemodeSwitchLabel.Text = 'Parallele mode';
            app.ParallelemodeSwitch = uiswitch(app.PreparedataTab, 'slider');
            app.ParallelemodeSwitch.Items = {'No', 'Yes'};
            app.ParallelemodeSwitch.Value = 'No';
            app.AnalysisTab = uitab(app.TabGroup);
            app.AnalysisTab.Title = 'Analysis';
            app.AnalysisTab.Units = 'pixels';
            app.DatasetswithRASListBoxLabel = uilabel(app.AnalysisTab);
            app.DatasetswithRASListBoxLabel.Text = {'Datasets'; 'with RAS'};
            app.DatasetswithRASListBox = uilistbox(app.AnalysisTab);
            app.DatasetswithRASListBox.Items = {};
            app.DatasetswithRASListBox.Multiselect = 'on';
            app.DatasetswithRASListBox.Value = {};
            app.TabGroup2 = uitabgroup(app.AnalysisTab);
            app.ComparedatasetTab = uitab(app.TabGroup2);
            app.ComparedatasetTab.Title = 'Compare dataset';
            app.ComparedatasetTab.Units = 'pixels';
            app.ComparedatasetButton = uibutton(app.ComparedatasetTab, 'push');
            app.ComparedatasetButton.ButtonPushedFcn = createCallbackFcn(app, @ComparedatasetButtonPushed, true);
            app.ComparedatasetButton.Text = 'Compare dataset';
            app.ModeDropDownLabel = uilabel(app.ComparedatasetTab);
            app.ModeDropDownLabel.Text = 'Mode';
            app.ModeDropDown = uidropdown(app.ComparedatasetTab);
            app.ModeDropDown.Items = {'Pairwise', 'One vs Others'};
            app.ModeDropDown.Value = 'Pairwise';
            app.HypotesistestDropDownLabel = uilabel(app.ComparedatasetTab);
            app.HypotesistestDropDownLabel.Text = 'Hypotesis test';
            app.HypotesistestDropDown = uidropdown(app.ComparedatasetTab);
            app.HypotesistestDropDown.Items = {'Kolmogorov - Smirnov', 'Kruskal - Wallis', 't student', 'One way anova', 'Do not compute'};
            app.HypotesistestDropDown.Value = 'Kolmogorov - Smirnov';
            app.FoldChangeDropDownLabel = uilabel(app.ComparedatasetTab);
            app.FoldChangeDropDownLabel.Text = 'Fold Change';
            app.FoldChangeDropDown = uidropdown(app.ComparedatasetTab);
            app.FoldChangeDropDown.Items = {'Compute and log2 scale', 'Compute', 'Do not compute'};
            app.FoldChangeDropDown.Value = 'Compute and log2 scale';
            app.ColormapButton = uibutton(app.ComparedatasetTab, 'push');
            app.ColormapButton.ButtonPushedFcn = createCallbackFcn(app, @ColormapButtonPushed, true);
            app.ColormapButton.Text = 'Color map';
            app.ClusteringTab = uitab(app.TabGroup2);
            app.ClusteringTab.Title = 'Clustering';
            app.ClusteringTab.Units = 'pixels';
            app.ExportDataButton = uibutton(app.AnalysisTab, 'push');
            app.ExportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDataButtonPushed, true);
            app.ExportDataButton.Text = 'Export Data';
        end
    end

    methods (Access = public)

        % Construct app
        function app = MaREA()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MaREAUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MaREAUIFigure)
        end
    end
end